classdef startScreen < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Button_500               matlab.ui.control.Button
        Button_100               matlab.ui.control.Button
        Button_50                matlab.ui.control.Button
        Button_10                matlab.ui.control.Button
        BetAmountEditFieldLabel  matlab.ui.control.Label
        BetAmountEditField       matlab.ui.control.NumericEditField
        ResetButton              matlab.ui.control.Button
        Button_1P                matlab.ui.control.Button
        Button_2P                matlab.ui.control.Button
        NumberofPlayersLabel     matlab.ui.control.Label
        QuitButton               matlab.ui.control.Button
        Bet1EditFieldLabel       matlab.ui.control.Label
        Bet1EditField            matlab.ui.control.NumericEditField
        Balance1EditFieldLabel   matlab.ui.control.Label
        Balance1EditField        matlab.ui.control.NumericEditField
        Bet2EditFieldLabel       matlab.ui.control.Label
        Bet2EditField            matlab.ui.control.NumericEditField
        Balance2EditFieldLabel   matlab.ui.control.Label
        Balance2EditField        matlab.ui.control.NumericEditField
        DoneButton               matlab.ui.control.Button
        ErrorLabel               matlab.ui.control.Label
    end

    properties (Access = public)
        gameWindow %open mainGame window
        numPlayers = 0; %number of players; 1 = 1-player, 2 = 2-players
        bet1 = 0;
        bet2 = 0;
        balance1
        balance2
        balance1_initial
        balance2_initial
        numDonePushed = 0; %number of time done button is pushed
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %betting and balance fields not visible until user selects number of players
            app.Bet1EditField.Visible = 'off';
            app.Bet1EditFieldLabel.Visible = 'off';
            app.Bet2EditField.Visible = 'off';
            app.Bet2EditFieldLabel.Visible = 'off';
            app.Balance1EditField.Visible = 'off';
            app.Balance1EditFieldLabel.Visible = 'off';
            app.Balance2EditField.Visible = 'off';
            app.Balance2EditFieldLabel.Visible = 'off';
            
            app.ErrorLabel.Visible = 'off';
            
            %Bookmark initial balance values for reset functionality
            %Rewrite Number Edit Fields as Labels similar to Player Scores in MainGame 
            %so it can't be edited mid game and initial values can be properly displayed 
            app.balance1_initial = 3000;
            app.balance1 = app.balance1_initial;
            app.balance2_initial = 2000;
            app.balance2 = app.balance2_initial;

        end

        % Button pushed function: Button_1P
        function Button_1PPushed(app, event)
            %ONE-PLAYER PLAY
            app.numPlayers = 1;
            % If only one player, then player 2 has no bet
            app.bet1 = 0;
            app.balance1 = app.balance1_initial;
            app.Bet1EditField.Value = app.bet1;
            app.Balance1EditField.Value = app.balance1;
            
            %reset numDonePushed for consistency in overall program
            %functionality. If player clicks "2" then "Done", then clicks
            %"1" (changes mind), program would break otherwise due to
            %conditions in other functions below
            app.numDonePushed = 0;
            
            app.Bet1EditField.Visible = 'on';
            app.Bet1EditFieldLabel.Visible = 'on';
            app.Balance1EditField.Visible = 'on';
            app.Balance1EditFieldLabel.Visible = 'on';
            
            if(app.numPlayers == 2)
            app.Bet2EditField.Visible = 'on';
            app.Bet2EditFieldLabel.Visible = 'on';
            app.Balance2EditField.Visible = 'on';
            app.Balance2EditFieldLabel.Visible = 'on';
            elseif(app.numPlayers == 1)
            app.Bet2EditField.Visible = 'off';
            app.Bet2EditFieldLabel.Visible = 'off';
            app.Balance2EditField.Visible = 'off';
            app.Balance2EditFieldLabel.Visible = 'off';
            else
            app.ErrorLabel.Visible = 'on'; %equivalent function of try-catch
            end
        end

        % Button pushed function: Button_2P
        function Button_2PPushed(app, event)
            %TWO-PLAYER PLAY
            app.numPlayers = 2;
            
            app.bet2 = 0;
            app.balance2 = app.balance2_initial;
            app.Bet2EditField.Value = app.bet2;
            app.Balance2EditField.Value = app.balance2;

            app.Bet1EditField.Visible = 'on';
            app.Bet1EditFieldLabel.Visible = 'on';
            app.Balance1EditField.Visible = 'on';
            app.Balance1EditFieldLabel.Visible = 'on';
            
            app.Bet2EditField.Visible = 'on';
            app.Bet2EditFieldLabel.Visible = 'on';
            app.Balance2EditField.Visible = 'on';
            app.Balance2EditFieldLabel.Visible = 'on';
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            %Done pushed opens mainGame.mlapp depending on if conditions:
            %ie. number of players and numDonePushed counter
            app.numDonePushed = app.numDonePushed + 1;
            if(app.numPlayers == 1 && app.numDonePushed == 1)
                app.gameWindow = mainGame(app, app.numPlayers, app.bet1, app.balance1, app.bet2, app.balance2);
                closereq;
            elseif(app.numPlayers == 2 && app.numDonePushed == 1)
                app.BetAmountEditField.Value = 0;
            elseif(app.numPlayers == 2 && app.numDonePushed == 2)
                app.gameWindow = mainGame(app, app.numPlayers, app.bet1, app.balance1, app.bet2, app.balance2);
                closereq;
            else
                app.ErrorLabel.Visible = 'on'; %equivalent function of try-catch
            end
        end

        % Button pushed function: Button_500
        function Button_500Pushed(app, event)
            %500 button functionality
            if(app.balance1 >= 500 && app.numDonePushed == 0)
                app.bet1 = app.bet1 + 500;
                app.balance1 = app.balance1 - 500;
                app.Bet1EditField.Value = app.bet1;
                app.Balance1EditField.Value = app.balance1;
                app.BetAmountEditField.Value = app.bet1;
            elseif(app.balance2 >= 500 && app.numDonePushed == 1)
                app.bet2 = app.bet2 + 500;
                app.balance2 = app.balance2 - 500;
                app.Bet2EditField.Value = app.bet2;
                app.Balance2EditField.Value = app.balance2;
                app.BetAmountEditField.Value = app.bet2;
            end
        end

        % Button pushed function: Button_100
        function Button_100Pushed(app, event)
            %100 button functionality 
            if(app.balance1 >= 100 && app.numDonePushed == 0)
                app.bet1 = app.bet1 + 100;
                app.balance1 = app.balance1 - 100;
                app.Bet1EditField.Value = app.bet1;
                app.Balance1EditField.Value = app.balance1;
                app.BetAmountEditField.Value = app.bet1;
            elseif(app.balance2 >= 100 && app.numDonePushed == 1)
                app.bet2 = app.bet2 + 100;
                app.balance2 = app.balance2 - 100;
                app.Bet2EditField.Value = app.bet2;
                app.Balance2EditField.Value = app.balance2;
                app.BetAmountEditField.Value = app.bet2;
            end
        end

        % Button pushed function: Button_50
        function Button_50Pushed(app, event)
            %50 button functionality  
            if(app.balance1 >= 50 && app.numDonePushed == 0)
                app.bet1 = app.bet1 + 50;
                app.balance1 = app.balance1 - 50;
                app.Bet1EditField.Value = app.bet1;
                app.Balance1EditField.Value = app.balance1;
                app.BetAmountEditField.Value = app.bet1;
            elseif(app.balance2 >= 50 && app.numDonePushed == 1)
                app.bet2 = app.bet2 + 50;
                app.balance2 = app.balance2 - 50;
                app.Bet2EditField.Value = app.bet2;
                app.Balance2EditField.Value = app.balance2;
                app.BetAmountEditField.Value = app.bet2;
            end
        end

        % Button pushed function: Button_10
        function Button_10Pushed(app, event)
            %10 button functionality   
            if(app.balance1 >= 10 && app.numDonePushed == 0)
                app.bet1 = app.bet1 + 10;
                app.balance1 = app.balance1 - 10;
                app.Bet1EditField.Value = app.bet1;
                app.Balance1EditField.Value = app.balance1;
                app.BetAmountEditField.Value = app.bet1;
            elseif(app.balance2 >= 10 && app.numDonePushed == 1)
                app.bet2 = app.bet2 + 10;
                app.balance2 = app.balance2 - 10;
                app.Bet2EditField.Value = app.bet2;
                app.Balance2EditField.Value = app.balance2;
                app.BetAmountEditField.Value = app.bet2;
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            %reset button functionality 
            switch app.numDonePushed 
                 case 0
                    app.bet1 = 0;
                    app.balance1 = app.balance1_initial;
                    app.Bet1EditField.Value = app.bet1;
                    app.Balance1EditField.Value = app.balance2_initial;
                    app.BetAmountEditField.Value = app.bet1;
                 case 1
                    app.bet2 = 0;
                    app.balance2 = 2000;
                    app.Bet2EditField.Value = app.bet2;
                    app.Balance2EditField.Value = app.balance2;
                    app.BetAmountEditField.Value = app.bet2;
            end
        end

        % Button pushed function: QuitButton
        function QuitButtonPushed(app, event)
            closereq;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Button_500 = uibutton(app.UIFigure, 'push');
            app.Button_500.ButtonPushedFcn = createCallbackFcn(app, @Button_500Pushed, true);
            app.Button_500.Icon = 'casinoChip.png';
            app.Button_500.Text = '500';
            app.Button_100 = uibutton(app.UIFigure, 'push');
            app.Button_100.ButtonPushedFcn = createCallbackFcn(app, @Button_100Pushed, true);
            app.Button_100.Icon = 'casinoChip.png';
            app.Button_100.Text = '100';
            app.Button_50 = uibutton(app.UIFigure, 'push');
            app.Button_50.ButtonPushedFcn = createCallbackFcn(app, @Button_50Pushed, true);
            app.Button_50.Icon = 'casinoChip.png';
            app.Button_50.Text = '50';
            app.Button_10 = uibutton(app.UIFigure, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @Button_10Pushed, true);
            app.Button_10.Icon = 'casinoChip.png';
            app.Button_10.Text = '10';
            app.BetAmountEditFieldLabel = uilabel(app.UIFigure);
            app.BetAmountEditFieldLabel.Text = 'Bet Amount';
            app.BetAmountEditField = uieditfield(app.UIFigure, 'numeric');
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.Button_1P = uibutton(app.UIFigure, 'push');
            app.Button_1P.ButtonPushedFcn = createCallbackFcn(app, @Button_1PPushed, true);
            app.Button_1P.Tooltip = {'If you have one player, Click "1", then click the amount you wish to bet. Then click "Done"'};
            app.Button_1P.Text = '1';
            app.Button_2P = uibutton(app.UIFigure, 'push');
            app.Button_2P.ButtonPushedFcn = createCallbackFcn(app, @Button_2PPushed, true);
            app.Button_2P.Tooltip = {'If you have 2 players, click "2". Player 1 should click the amount they wish to bet then click "Done". Player 2 should do the same.'};
            app.Button_2P.Text = '2';
            app.NumberofPlayersLabel = uilabel(app.UIFigure);
            app.NumberofPlayersLabel.Text = 'Number of Players';
            app.QuitButton = uibutton(app.UIFigure, 'push');
            app.QuitButton.ButtonPushedFcn = createCallbackFcn(app, @QuitButtonPushed, true);
            app.QuitButton.Text = 'Quit';
            app.Bet1EditFieldLabel = uilabel(app.UIFigure);
            app.Bet1EditFieldLabel.Text = 'Bet 1';
            app.Bet1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Balance1EditFieldLabel = uilabel(app.UIFigure);
            app.Balance1EditFieldLabel.Text = 'Balance 1';
            app.Balance1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Balance1EditField.Value = 2000;
            app.Bet2EditFieldLabel = uilabel(app.UIFigure);
            app.Bet2EditFieldLabel.Text = 'Bet 2';
            app.Bet2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Balance2EditFieldLabel = uilabel(app.UIFigure);
            app.Balance2EditFieldLabel.Text = 'Balance 2';
            app.Balance2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Balance2EditField.Value = 2000;
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.ErrorLabel = uilabel(app.UIFigure);
            app.ErrorLabel.Text = 'Error!!!';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = startScreen

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end