classdef mainBlackjack < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        BLACKJACKLabel          matlab.ui.control.Label
        QuitButton              matlab.ui.control.Button
        PlayersEditFieldLabel   matlab.ui.control.Label
        PlayersEditField        matlab.ui.control.NumericEditField
        BetEditFieldLabel       matlab.ui.control.Label
        BetEditField            matlab.ui.control.NumericEditField
        BalanceEditFieldLabel   matlab.ui.control.Label
        BalanceEditField        matlab.ui.control.NumericEditField
        Bet2EditFieldLabel      matlab.ui.control.Label
        Bet2EditField           matlab.ui.control.NumericEditField
        Balance2EditFieldLabel  matlab.ui.control.Label
        Balance2EditField       matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        CallingApp
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, numPlayers, betAmount, balance, betAmount2, balance2)
            app.CallingApp = mainapp;
            
            % Update UI with input values
            app.PlayersEditField.Value = numPlayers;
            app.BetEditField.Value = betAmount;
            app.BalanceEditField.Value = balance;
            
            app.Bet2EditField.Value = betAmount2;
            app.Balance2EditField.Value = balance2;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.BLACKJACKLabel = uilabel(app.UIFigure);
            app.BLACKJACKLabel.Text = 'BLACKJACK';
            app.QuitButton = uibutton(app.UIFigure, 'push');
            app.QuitButton.Text = 'Quit?';
            app.PlayersEditFieldLabel = uilabel(app.UIFigure);
            app.PlayersEditFieldLabel.Text = 'Players';
            app.PlayersEditField = uieditfield(app.UIFigure, 'numeric');
            app.BetEditFieldLabel = uilabel(app.UIFigure);
            app.BetEditFieldLabel.Text = 'Bet';
            app.BetEditField = uieditfield(app.UIFigure, 'numeric');
            app.BalanceEditFieldLabel = uilabel(app.UIFigure);
            app.BalanceEditFieldLabel.Text = 'Balance';
            app.BalanceEditField = uieditfield(app.UIFigure, 'numeric');
            app.Bet2EditFieldLabel = uilabel(app.UIFigure);
            app.Bet2EditFieldLabel.Text = 'Bet 2';
            app.Bet2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Balance2EditFieldLabel = uilabel(app.UIFigure);
            app.Balance2EditFieldLabel.Text = 'Balance 2';
            app.Balance2EditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mainBlackjack(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end